classdef Project_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        ThresholdKnobLabel              matlab.ui.control.Label
        ThresholdKnob                   matlab.ui.control.Knob
        AttenuationfactorKnobLabel      matlab.ui.control.Label
        AttenuationfactorKnob           matlab.ui.control.Knob
        PlotsButtonGroup                matlab.ui.container.ButtonGroup
        InputSignalTimeDomainButton     matlab.ui.control.RadioButton
        NoiseSignalTimeDomainButton     matlab.ui.control.RadioButton
        InputSignalSpectrogramButton    matlab.ui.control.RadioButton
        NoiseSignalFourierButton        matlab.ui.control.RadioButton
        InputSignalFourierButton        matlab.ui.control.RadioButton
        FilteredSignalTimeDomainButton  matlab.ui.control.RadioButton
        FilteredSignalFourierButton     matlab.ui.control.RadioButton
        FilteredSignalSpectrogramButton  matlab.ui.control.RadioButton
        Button                          matlab.ui.control.Button
        COMPUTEButton                   matlab.ui.control.StateButton
        BandwidthKnob_2Label            matlab.ui.control.Label
        BandwidthKnob_2                 matlab.ui.control.DiscreteKnob
        PLOTButton_2                    matlab.ui.control.StateButton
        PLAYBACKInputButton             matlab.ui.control.StateButton
        PLAYBACKFILTERButton            matlab.ui.control.StateButton
        PLAYBACKnoiseButton             matlab.ui.control.StateButton
        GainKnobLabel                   matlab.ui.control.Label
        GainKnob                        matlab.ui.control.Knob
        GainLossThresholdLabel          matlab.ui.control.Label
        Th_2                            matlab.ui.control.Knob
        LossKnobLabel                   matlab.ui.control.Label
        LossKnob                        matlab.ui.control.Knob
        NoiseCancellingApplicationLabel  matlab.ui.control.Label
        ApproachesButtonGroup           matlab.ui.container.ButtonGroup
        FiltersButton                   matlab.ui.control.RadioButton
        WindowingSTFTButton             matlab.ui.control.RadioButton
        AudioSlectionButtonGroup        matlab.ui.container.ButtonGroup
        NormalNoiseButton               matlab.ui.control.ToggleButton
        BlenderButton                   matlab.ui.control.ToggleButton
        NYSurroundingsButton            matlab.ui.control.ToggleButton
        FilterKnobsLabel                matlab.ui.control.Label
        GeneralKnobsLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        noise
        voice
        t
        noise_fourier
        voice_fourier
        f
        noise_filterd
        voice_filtered
        voice_fourier_filtered
        P
        F
        T
        P_f
        F_f
        T_f
        t_filter
        f_filt
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: COMPUTEButton
        function COMPUTEButtonValueChanged(app, event)
            % Selection of audio
            if app.NormalNoiseButton.Value == true
                app.noise= audioread('noise.wav');             
                app.voice= audioread('noise_and_voice.wav');
            elseif app.BlenderButton.Value == true
                app.noise= audioread('noise_b.wav');             
                app.voice= audioread('noise_and_voice_b.wav');
            elseif app.NYSurroundingsButton.Value == true
                app.noise= audioread('noise_ny.wav');             
                app.voice= audioread('noise_and_voice_ny.wav');
            end
            
            if app.FiltersButton.Value == true  
                value = app.COMPUTEButton.Value;  
                
                if value == 1
                    fs=44100;                                         %sampling frequency
                    samples_voice=length(app.voice);                  %length of voice+noise
                    samples_noise=length(app.noise);                  %length of noise 
                    Ts=1/fs;                                          %sampling period
                    s=Ts*samples_noise;
            
                    app.noise_fourier = abs(fft(app.noise));          %fft of noise
                    app.voice_fourier = abs(fft(app.voice));          %fft of voice+noise 

                    app.t=(0:Ts:s-Ts)';                               %time interval
                    app.f=fs*[0:samples_noise-1]/samples_noise-fs/2;  %frequency interval
                
                    [app.P,app.F,app.T]= pspectrum(app.voice,app.t,"spectrogram")
                    
                    %Knob that changes the value to find local maxima
                    %of noise above threshold
                    threshold = app.ThresholdKnob.Value;
                    %find local maxima of noise_fourier above MinProminence according to f SamplePoints
                    maxIndices = islocalmax(app.noise_fourier,"MinProminence",threshold,"SamplePoints",app.f);
            
                    %Frecuencies to attenuate
                    f0=app.f(samples_noise/2+1:end);        %Reading half of frequency interval
                    max_f0 = maxIndices(1:(samples_noise/2));%maxima in the half frequency interval
                    f0_vector = f0'.*max_f0;            %frequencies with maxima indices
                    
                    bw = (1/(fs/2))*(app.BandwidthKnob_2.Value);  %Knob that changes the bandwith
            
                    app.noise_filterd = app.noise; %Initialize noise_filtered
                    app.voice_filtered = app.voice; %Initialize voice_filtered
            
                    %Knob that changes how much in dB each component is affected
                    attenuation_factor = app.AttenuationfactorKnob.Value;  %In db
               
                    m = max(app.noise_fourier);%Maximum value in frequency domain
            
                    for i = 1:samples_noise/2
                        w0 = f0_vector(i,1)/(fs/2);%Normalized frequency where notch is located
                        if w0~= 0                  %Check if contains frequencies
                            at = app.noise_fourier(i,1)/m;%attenuation proportion
                            [b,a] = iirnotch(w0,bw,at*attenuation_factor);%Obtaining coefficients of filter notch
                            app.voice_filtered=filter(b,a,app.voice_filtered);%Filtering 
                        
                        end
                    end
                    app.voice_fourier_filtered = fft(app.voice_filtered);%FFT of voice_filtered
                
                    %Change of Volumen in Frequency Domain
                    for i = 1:samples_noise
                        if abs(app.voice_fourier_filtered(i,1)) > app.Th_2.Value;%Compare value with threshold
                            app.voice_fourier_filtered(i,1) = app.voice_fourier_filtered(i,1)*app.GainKnob.Value;%Amplifying signal above threshold
                        else
                            app.voice_fourier_filtered(i,1) = app.voice_fourier_filtered(i,1)*app.LossKnob.Value;%Noise attenuation below threshold
                        end
                    end
                
                    %% Plots of result
                    app.voice_filtered = ifft(app.voice_fourier_filtered);%IFFT of filtered signal
                    app.voice_fourier_filtered = real(abs(app.voice_fourier_filtered));
                    app.t_filter = app.t;
                    app.f_filt = app.f;
                    [app.P_f,app.F_f,app.T_f] = pspectrum(app.voice_filtered,app.t,"spectrogram")%Spectogram of filtered signal
                
                    %Change State
                    app.COMPUTEButton.Value = 0;
                end
                
            elseif app.WindowingSTFTButton.Value
                value = app.COMPUTEButton.Value;  
                
                if value == 1
                    %TODO LO NUEVO
                    Fs=44100;                               %Sampling frequency
                    Ts=1/Fs;                                %Sampling period
                    samples_voice=length(app.voice);            %Length of voice+noise
                    samples_noise=length(app.noise);            %Length of noise(shoud be equal to voice+noise) 
                    s=Ts*samples_noise;                     %Sampling time in seconds            
                    app.t=(0:Ts:s-Ts)';                         %time interval
                    app.f=Fs*[0:samples_noise-1]/samples_noise-Fs/2;%frequency interval
                    
                    %% Applying FFT to noise and voie+noise
                    app.noise_fourier = abs(fft(app.noise));        %fft magnitude of noise
                    app.voice_fourier = abs(fft(app.voice));        %fft magnitude of voice+noise
                    
                    [app.P,app.F,app.T] = pspectrum(app.voice,app.t,"spectrogram")
                    
                    %% Short Time Fourier Transform 
                    % define the analysis and synthesis parameters
                    wlen=1024;                              
                    hop = wlen/8;                           %hop size
                    nfft = 4*wlen;                          %number of FFT points
                    
                    % generate analysis and synthesis windows
                    anal_win = blackmanharris(wlen, 'periodic'); 
                    synth_win = hamming(wlen, 'periodic');
                    
                    voice_len = length(app.voice);             % determination of the signal length 
                    wlen = length(anal_win);               % determination of the window length
                    
                    % stft matrix size estimation and preallocation
                    NUP = ceil((1+nfft)/2);         % calculate the number of unique fft points
                    L = 1+fix((voice_len-wlen)/hop);    % calculate the number of signal frames
                    STFT = zeros(NUP, L);                  % preallocate the stft matrix
                    % STFT (via time-localized FFT)
                    for l = 0:L-1
                        xw = app.voice(1+l*hop : wlen+l*hop).*anal_win; % windowing
                        X = fft(xw, nfft);  % FFT
                        STFT(:, 1+l) = X(1:NUP);           % update of the stft matrix
                    end
                    
                    % calculation of the time and frequency vectors
                    STFT_t = (wlen/2:hop:wlen/2+(L-1)*hop)/Fs;
                    STFT_f = (0:NUP-1)*Fs/nfft;
                    %% Noise Cancellation
                    Factor = app.Th_2.Value;                          %Multiplication factor                 
                    Th_noise = app.noise_fourier.*Factor;      %Noise threshold  
                    reduction = app.LossKnob.Value;                  %Noise cancellation multiplication factor
                    amp = app.GainKnob.Value;                               %Signal amplificaction factor
                    
                    for i = 1:size(STFT,1)                 %row-time
                        for j = 1:size(STFT,2)             %col-frequency
                            if abs(Th_noise(i,1)) > abs(STFT(i,j))%Check if noise threshold is greater than the value
                                STFT(i,j) = (STFT(i,j)).*reduction; %Noise attenuation
                            else
                                STFT(i,j) = (STFT(i,j)).*amp; %Voice signal amplifying
                            end
                        end
                    end
                    
                    %% Inverse Short Time Fourier Transform(ISTFT)
                    L2 = size(STFT, 2);                 % determine the number of signal frames
                    wlen2 = length(synth_win);   % determine the length of the synthesis window
                    voice_filtered_len = wlen2+(L2-1)*hop;%estimate the length of the signal vector
                    app.voice_filtered = zeros(1, voice_filtered_len);%preallocate the signal vector
                    % reconstruction of the whole spectrum
                    if rem(nfft, 2)             
                        % odd nfft excludes Nyquist point
                        X = [STFT; conj(flipud(STFT(2:end, :)))];
                    else                        
                        % even nfft includes Nyquist point
                        X = [STFT; conj(flipud(STFT(2:end-1, :)))];
                    end
                    % columnwise IFFT on the STFT-matrix
                    xw = real(ifft(X));
                    xw = xw(1:wlen2, :);
                    % Weighted-OLA
                    for l = 1:L2
                        app.voice_filtered(1+(l-1)*hop : wlen2+(l-1)*hop) = app.voice_filtered(1+(l-1)*hop : wlen2+(l-1)*hop) + ...
                                                          (xw(:, l).*synth_win)';
                    end
                    W0 = sum(anal_win.*synth_win);         % scaling of the signal 
                    
                    %Time Domain Plots
                    app.voice_filtered = app.voice_filtered.*hop/W0;   
                    app.t_filter= (0:voice_filtered_len-1)/Fs; % generation of the time vector
                    
                    %Frequency plots
                    app.f_filt = Fs*[0:size(app.voice_filtered,2)-1]/size(app.voice_filtered,2)-Fs/2;
                    app.voice_fourier_filtered=abs(fft(app.voice_filtered));
                    
                    %Spectrogram
                    [app.P_f,app.F_f,app.T_f] = pspectrum(app.voice_filtered,app.t_filter,"spectrogram")
                    
                    %Change State
                    app.COMPUTEButton.Value = 0;
                end
            end
        end

        % Value changed function: PLOTButton_2
        function PLOTButton_2ValueChanged(app, event)
            value = app.PLOTButton_2.Value;
            if value == 1
                if app.InputSignalTimeDomainButton.Value == true 
                    x = app.voice;
                    y = app.t;
                    plot(app.UIAxes,y,x); %Plot of noise+voice signal in time domain
                    xlabel(app.UIAxes, 'Time [s]');
                    ylabel(app.UIAxes, 'Amplitude');
                elseif app.InputSignalFourierButton.Value == true
                    x = fftshift(app.voice_fourier);
                    y = app.f;
                    plot(app.UIAxes,y,x); %Plot of noise+voice signal in frequency domain
                    xlabel(app.UIAxes, 'Frequency [Hz]');
                    ylabel(app.UIAxes, 'Amplitude');
                elseif app.NoiseSignalTimeDomainButton.Value == true
                    x = app.noise;
                    y = app.t;
                    plot(app.UIAxes,y,x); %Plot of noise signal in time domain
                    xlabel(app.UIAxes, 'Time [s]');
                    ylabel(app.UIAxes, 'Amplitude');
                elseif app.NoiseSignalFourierButton.Value == true
                    x = fftshift(app.noise_fourier);
                    y = app.f;
                    plot(app.UIAxes,y,x); %Plot of noise signal in frequency domain
                    xlabel(app.UIAxes, 'Frequency [Hz]');
                    ylabel(app.UIAxes, 'Amplitude');
                elseif app.InputSignalSpectrogramButton.Value == true
                    imagesc(app.UIAxes, app.T, app.F, log(app.P)); %Spectogram of noise+voice signal
                    set(app.UIAxes,'YDir', 'normal');
                    xlabel(app.UIAxes, 'Time [s]');
                    ylabel(app.UIAxes, 'Frequency [Hz]');
                    colorbar(app.UIAxes)
                elseif app.FilteredSignalTimeDomainButton.Value == true
                    x = app.voice_filtered;
                    y = app.t_filter;
                    plot(app.UIAxes,y,x,'r'); %Plot of filtered signal in time domain
                    xlabel(app.UIAxes, 'Time [s]');
                    ylabel(app.UIAxes, 'Amplitude');
                elseif app.FilteredSignalFourierButton.Value == true
                    x = fftshift(app.voice_fourier_filtered);
                    y = app.f_filt;
                    plot(app.UIAxes,y,x,'r'); %Plot of filtered signal  in freqeuncy domain
                    xlabel(app.UIAxes, 'Frequency [Hz]');
                    ylabel(app.UIAxes, 'Amplitude');
                elseif app.FilteredSignalSpectrogramButton.Value == true
                    imagesc(app.UIAxes, app.T_f, app.F_f, log(app.P_f)); %Spectogram of filtered signal
                    set(app.UIAxes,'YDir', 'normal');
                    xlabel(app.UIAxes, 'Time [s]');
                    ylabel(app.UIAxes, 'Frequency [Hz]');
                    colorbar(app.UIAxes)
                end
        
                %Change State
                app.PLOTButton_2.Value = 0; 
            end
        end

        % Value changed function: PLAYBACKInputButton
        function PLAYBACKInputButtonValueChanged(app, event)
            value = app.PLAYBACKInputButton.Value;
            fs= 44100;
            if value == 1
                sound(app.voice,fs); %Play noise+voice signal
                app.PLAYBACKInputButton.Value = 0;
            end
        end

        % Value changed function: PLAYBACKFILTERButton
        function PLAYBACKFILTERButtonValueChanged(app, event)
            value = app.PLAYBACKFILTERButton.Value;
            fs= 44100;
            if value == 1
                sound(app.voice_filtered,fs); %Play filtered signal
                app.PLAYBACKFILTERButton.Value = 0;
            end            
        end

        % Value changed function: PLAYBACKnoiseButton
        function PLAYBACKnoiseButtonValueChanged(app, event)
            value = app.PLAYBACKnoiseButton.Value;
            fs= 44100;
            if value == 1
                sound(app.noise,fs); %Play noise signal
                app.PLAYBACKnoiseButton.Value = 0;
            end 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ThresholdKnobLabel = uilabel(app.UIFigure);
            app.ThresholdKnobLabel.Text = 'Threshold';
            app.ThresholdKnob = uiknob(app.UIFigure, 'continuous');
            app.ThresholdKnob.Limits = [1 200];
            app.ThresholdKnob.MajorTicks = [1 20 40 60 80 100 120 140 160 180 200];
            app.ThresholdKnob.Value = 1;
            app.AttenuationfactorKnobLabel = uilabel(app.UIFigure);
            app.AttenuationfactorKnobLabel.Text = 'Attenuation factor';
            app.AttenuationfactorKnob = uiknob(app.UIFigure, 'continuous');
            app.AttenuationfactorKnob.Limits = [0 50];
            app.AttenuationfactorKnob.Value = 1;
            app.PlotsButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotsButtonGroup.Title = 'Plots';
            app.InputSignalTimeDomainButton = uiradiobutton(app.PlotsButtonGroup);
            app.InputSignalTimeDomainButton.Text = 'Input Signal [Time Domain]';
            app.InputSignalTimeDomainButton.Value = true;
            app.NoiseSignalTimeDomainButton = uiradiobutton(app.PlotsButtonGroup);
            app.NoiseSignalTimeDomainButton.Text = 'Noise Signal [Time Domain]';
            app.InputSignalSpectrogramButton = uiradiobutton(app.PlotsButtonGroup);
            app.InputSignalSpectrogramButton.Text = 'Input Signal [Spectrogram]';
            app.NoiseSignalFourierButton = uiradiobutton(app.PlotsButtonGroup);
            app.NoiseSignalFourierButton.Text = 'Noise Signal [Fourier]';
            app.InputSignalFourierButton = uiradiobutton(app.PlotsButtonGroup);
            app.InputSignalFourierButton.Text = 'Input Signal [Fourier]';
            app.FilteredSignalTimeDomainButton = uiradiobutton(app.PlotsButtonGroup);
            app.FilteredSignalTimeDomainButton.Text = 'Filtered Signal [Time Domain]';
            app.FilteredSignalFourierButton = uiradiobutton(app.PlotsButtonGroup);
            app.FilteredSignalFourierButton.Text = 'Filtered Signal [Fourier]';
            app.FilteredSignalSpectrogramButton = uiradiobutton(app.PlotsButtonGroup);
            app.FilteredSignalSpectrogramButton.Text = 'Filtered Signal [Spectrogram]';
            app.Button = uibutton(app.UIFigure, 'push');
            app.COMPUTEButton = uibutton(app.UIFigure, 'state');
            app.COMPUTEButton.ValueChangedFcn = createCallbackFcn(app, @COMPUTEButtonValueChanged, true);
            app.COMPUTEButton.Text = 'COMPUTE';
            app.BandwidthKnob_2Label = uilabel(app.UIFigure);
            app.BandwidthKnob_2Label.Text = 'Bandwidth';
            app.BandwidthKnob_2 = uiknob(app.UIFigure, 'discrete');
            app.BandwidthKnob_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.BandwidthKnob_2.Value = '1';
            app.PLOTButton_2 = uibutton(app.UIFigure, 'state');
            app.PLOTButton_2.ValueChangedFcn = createCallbackFcn(app, @PLOTButton_2ValueChanged, true);
            app.PLOTButton_2.Text = 'PLOT';
            app.PLAYBACKInputButton = uibutton(app.UIFigure, 'state');
            app.PLAYBACKInputButton.ValueChangedFcn = createCallbackFcn(app, @PLAYBACKInputButtonValueChanged, true);
            app.PLAYBACKInputButton.Text = 'PLAYBACK Input';
            app.PLAYBACKFILTERButton = uibutton(app.UIFigure, 'state');
            app.PLAYBACKFILTERButton.ValueChangedFcn = createCallbackFcn(app, @PLAYBACKFILTERButtonValueChanged, true);
            app.PLAYBACKFILTERButton.Text = 'PLAYBACK FILTER';
            app.PLAYBACKnoiseButton = uibutton(app.UIFigure, 'state');
            app.PLAYBACKnoiseButton.ValueChangedFcn = createCallbackFcn(app, @PLAYBACKnoiseButtonValueChanged, true);
            app.PLAYBACKnoiseButton.Text = 'PLAYBACK noise';
            app.GainKnobLabel = uilabel(app.UIFigure);
            app.GainKnobLabel.Text = 'Gain';
            app.GainKnob = uiknob(app.UIFigure, 'continuous');
            app.GainKnob.Limits = [1 10];
            app.GainKnob.Value = 1;
            app.GainLossThresholdLabel = uilabel(app.UIFigure);
            app.GainLossThresholdLabel.Text = 'Gain/Loss Threshold';
            app.Th_2 = uiknob(app.UIFigure, 'continuous');
            app.Th_2.Limits = [0 50];
            app.LossKnobLabel = uilabel(app.UIFigure);
            app.LossKnobLabel.Text = 'Loss';
            app.LossKnob = uiknob(app.UIFigure, 'continuous');
            app.LossKnob.Limits = [0 1];
            app.LossKnob.Value = 1;
            app.NoiseCancellingApplicationLabel = uilabel(app.UIFigure);
            app.NoiseCancellingApplicationLabel.Text = 'Noise Cancelling Application ';
            app.ApproachesButtonGroup = uibuttongroup(app.UIFigure);
            app.ApproachesButtonGroup.Title = 'Approaches ';
            app.FiltersButton = uiradiobutton(app.ApproachesButtonGroup);
            app.FiltersButton.Text = 'Filters';
            app.FiltersButton.Value = true;
            app.WindowingSTFTButton = uiradiobutton(app.ApproachesButtonGroup);
            app.WindowingSTFTButton.Text = 'Windowing (STFT)';
            app.AudioSlectionButtonGroup = uibuttongroup(app.UIFigure);
            app.AudioSlectionButtonGroup.Title = 'Audio Slection';
            app.NormalNoiseButton = uitogglebutton(app.AudioSlectionButtonGroup);
            app.NormalNoiseButton.Text = 'Normal Noise';
            app.NormalNoiseButton.Value = true;
            app.BlenderButton = uitogglebutton(app.AudioSlectionButtonGroup);
            app.BlenderButton.Text = 'Blender';
            app.NYSurroundingsButton = uitogglebutton(app.AudioSlectionButtonGroup);
            app.NYSurroundingsButton.Text = 'NY Surroundings ';
            app.FilterKnobsLabel = uilabel(app.UIFigure);
            app.FilterKnobsLabel.Text = 'Filter Knobs';
            app.GeneralKnobsLabel = uilabel(app.UIFigure);
            app.GeneralKnobsLabel.Text = 'General Knobs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end